classdef ProjectFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        UploadImageTab                  matlab.ui.container.Tab
        BrowseButton                    matlab.ui.control.Button
        ImageNameEditField              matlab.ui.control.EditField
        ImageNameEditFieldLabel         matlab.ui.control.Label
        UploadyourcolorimagehereLabel   matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        NoiseModelingTab                matlab.ui.container.Tab
        CalculateButton_3               matlab.ui.control.Button
        CalculateButton_2               matlab.ui.control.Button
        EntercorruptionrateEditField    matlab.ui.control.NumericEditField
        EntercorruptionrateEditFieldLabel  matlab.ui.control.Label
        EntercoefficientEditField       matlab.ui.control.NumericEditField
        EntercoefficientEditFieldLabel  matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        alongwiththechannelsyouwanttouseLabel  matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        RandomImpulseNoiseCheckBox      matlab.ui.control.CheckBox
        AdditiveGaussianNoiseCheckBox   matlab.ui.control.CheckBox
        SelectwhichtypeofnoiseyouwouldliketoincorporateLabel  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        FiltersTab                      matlab.ui.container.Tab
        sEditField                      matlab.ui.control.NumericEditField
        sEditFieldLabel                 matlab.ui.control.Label
        rEditField                      matlab.ui.control.NumericEditField
        rEditFieldLabel                 matlab.ui.control.Label
        ChannelDropDown_2               matlab.ui.control.DropDown
        ChannelDropDown_2Label          matlab.ui.control.Label
        BrowseButton_2                  matlab.ui.control.Button
        ImportimageyouwouldliketofilterEditField  matlab.ui.control.EditField
        ImportimageyouwouldliketofilterEditFieldLabel  matlab.ui.control.Label
        SaveButton_5                    matlab.ui.control.Button
        SaveButton_4                    matlab.ui.control.Button
        SaveButton_3                    matlab.ui.control.Button
        RankorderEVmeanCheckBox         matlab.ui.control.CheckBox
        ImpluseNoiseDRIDCheckBox        matlab.ui.control.CheckBox
        MedianFilter3x3windowCheckBox   matlab.ui.control.CheckBox
        SelectthefollowingfiltersyouwouldlikeimplementedLabel  matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        StatisticalCharacteristicsTab   matlab.ui.container.Tab
        BrowseButton_5                  matlab.ui.control.Button
        BrowseButton_4                  matlab.ui.control.Button
        BrowsetocalculateButton         matlab.ui.control.Button
        ImportsecondimageEditField      matlab.ui.control.EditField
        ImportsecondimageEditFieldLabel  matlab.ui.control.Label
        ImportfirstimageEditField       matlab.ui.control.EditField
        ImportfirstimageEditFieldLabel  matlab.ui.control.Label
        CalculateButton_4               matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        ImportimagetofindmeanandstandarddeviationEditField  matlab.ui.control.EditField
        ImportimagetofindmeanandstandarddeviationEditFieldLabel  matlab.ui.control.Label
        ChannelDropDown_3               matlab.ui.control.DropDown
        ChannelDropDown_3Label          matlab.ui.control.Label
        forstatisticalcharacteristicsLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        image = NaN; % Description
        noiseimage = NaN;
        NoiseImageGaussian; % For Gaussian noise
        NoiseImageImpulse;  % For Impulse noise
        FilteredImage_Median; % For Median filter 
        FilteredImage_EV; % For Rank order EV filter 
        FilteredImage_DRID;  
        mean_std_image;
        image1;
        image2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};

            [file, path] = uigetfile("*.tif", "Select an Image File");
            
            if isequal(file, 0)
               return; % Exit the function if no file is selected
            end

            fullFilePath = fullfile(path, file);
            app.image = imread(fullFilePath);

            app.ImageNameEditField.Value = file;

            I = imshow(app.image, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);

            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];

        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            
            value = app.AdditiveGaussianNoiseCheckBox.Value;
                coefficient = app.EntercoefficientEditField.Value;
                channel = app.ChannelDropDown.Value; 
                image = app.image; 

            if value
                NoiseImage = Gaussian_additive_noise(image, coefficient,channel);
                app.NoiseImageGaussian = NoiseImage;
            
                I = imshow(NoiseImage, 'Parent', app.UIAxes2, ...
                'XData', [1 app.UIAxes2.Position(3)], ...
                'YData', [1 app.UIAxes2.Position(4)]);
                
                % Set limits of axes
                app.UIAxes2.XLim = [0 I.XData(2)];
                app.UIAxes2.YLim = [0 I.YData(2)];
            else 
                cla(app.UIAxes2);
            end 

        function [NoiseImage] = Gaussian_additive_noise(image, coefficient,channel)
               
                if(channel == "Luminosity")
                    YCBCR = rgb2ycbcr(image);
                    [YImage, cbImage, crImage] = imsplit(YCBCR);

                    image2 = double(YImage); % Converting unit8 type to double
                    
                    meanSum = 0 ;               % Setting a sum variable to calculate mean 
                    sum = 0;                    % Setting a sum varibale which will be used to calculate variance 
                    
                    [rows, columns] = size(YImage);

                    for i = 1:1:rows  
                        for j = 1:1:columns
                            meanSum = meanSum + image2(i,j);  % Updating sum variable 
                        end 
                    end 
                    
                    mean = meanSum/(rows*columns);           % Finding the mean (sampling mean)
                    
                                                             % Looping through the image arry to find the Variance 
                    for i = 1:1:rows  
                        for j = 1:1:columns
                            x = image2(i,j) - mean;
                            sum = sum + x^2;                 % Updating sum variable 
                        end 
                    end 
                    
                    variance = sum/(rows*columns);           % Finding the variance (sampling variance)
                    stand = sqrt(variance);                  % Finding the standard deviation 
                    
                    Noise = randn(rows, columns);
                    Noise = (stand * coefficient) * Noise + mean;
            
                    % Add noise to the luminance channel
                    NoiseImage = image2 + Noise - mean; % take mean away?

                    NoiseImage = uint8(NoiseImage);
                    NoiseImage = cat(3, NoiseImage, cbImage, crImage);
                    NoiseImage =  ycbcr2rgb(NoiseImage);

                else 
                    [R, G, B] = imsplit(image);

                    imageR = double(R);
                    imageG = double(G);
                    imageB = double(B);

                    meanSumR = 0 ;               % Setting a sum variable to calculate mean 
                    sumR = 0;                    % Setting a sum varibale which will be used to calculate variance 
                    meanSumG = 0 ;               % Setting a sum variable to calculate mean 
                    sumG = 0;                    % Setting a sum varibale which will be used to calculate variance 
                    meanSumB = 0 ;               % Setting a sum variable to calculate mean 
                    sumB = 0;                    % Setting a sum varibale which will be used to calculate variance 

                    ImageDimensions = size(image); % Finding the dimensions of the image array,rows being N and columns as M
                    rows = ImageDimensions(1,1);
                    columns = ImageDimensions(1,2);

                    for i = 1:1:rows  
                        for j = 1:1:columns
                            meanSumR = meanSumR + imageR(i,j);  % Updating sum variable 
                            meanSumG = meanSumG + imageG(i,j);  % Updating sum variable
                            meanSumB = meanSumB + imageB(i,j);  % Updating sum variable
                        end 
                    end 

                    meanR = meanSumR/(rows*columns);           % Finding the mean (sampling mean)
                    meanG = meanSumG/(rows*columns);           % Finding the mean (sampling mean)
                    meanB = meanSumB/(rows*columns);           % Finding the mean (sampling mean)

                    for i = 1:1:rows  
                        for j = 1:1:columns
                            xR = imageR(i,j) - meanR;
                            xG = imageG(i,j) - meanG;
                            xB = imageB(i,j) - meanB;
                            sumR = sumR + xR^2;                 % Updating sum variable 
                            sumG = sumG + xG^2;                 % Updating sum variable 
                            sumB = sumB + xB^2;                 % Updating sum variable 
                        end 
                    end 

                    varianceR = sumR/(rows*columns);           % Finding the variance (sampling variance)
                    standR = sqrt(varianceR);                  % Finding the standard deviation 

                    varianceG = sumG/(rows*columns);           % Finding the variance (sampling variance)
                    standG = sqrt(varianceG);                  % Finding the standard deviation 
                    
                    varianceB = sumB/(rows*columns);           % Finding the variance (sampling variance)
                    standB = sqrt(varianceB);                  % Finding the standard deviation 
                    
                        % Generate Gaussian noise for each channel
                        NoiseR = (standR * coefficient) * randn(rows, columns) + meanR;
                        NoiseG = (standG * coefficient) * randn(rows, columns) + meanG;
                        NoiseB = (standB * coefficient) * randn(rows, columns) + meanB;
                
                        % Add noise to each channel
                        NoiseImageR = imageR + NoiseR - meanR; %remove means?
                        NoiseImageG = imageG + NoiseG - meanG;
                        NoiseImageB = imageB + NoiseB - meanB;
                
                        % combine channels
                        NoiseImageR = uint8(min(max(NoiseImageR, 0), 255));
                        NoiseImageG = uint8(min(max(NoiseImageG, 0), 255));
                        NoiseImageB = uint8(min(max(NoiseImageB, 0), 255));
                
                        NoiseImage = cat(3, NoiseImageR, NoiseImageG, NoiseImageB);
                    
                end                                      
             end

% ----------- END OF GAUSSIAN NOISE FUNCTION -----------------%%%
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
                        
            image = app.image; 
            corruption_rate = app.EntercorruptionrateEditField.Value; 
            channel = app.ChannelDropDown.Value;
            value = app.RandomImpulseNoiseCheckBox.Value;

            if value
                    NoiseImage = impulse_noise(image, corruption_rate,channel);
                    app.NoiseImageImpulse = NoiseImage;
            
                    I = imshow(NoiseImage, 'Parent', app.UIAxes3, ...
                    'XData', [1 app.UIAxes3.Position(3)], ...
                    'YData', [1 app.UIAxes3.Position(4)]);
                    
                    % Set limits of axes
                    app.UIAxes3.XLim = [0 I.XData(2)];
                    app.UIAxes3.YLim = [0 I.YData(2)];
            else 
                cla(app.UIAxes3);
            end 


        function [NoiseImage] = impulse_noise(image,corruption_rate, channel) 
               
                if(channel == "Luminosity")

                    YCBCR = rgb2ycbcr(image);
                    [YImage, cbImage, crImage] = imsplit(YCBCR);
                    image2 = double(YImage);
                        
                        ImageDimensions = size(image); % Finding the dimensions of the image array,rows being N and columns as M
                        rows = ImageDimensions(1,1);
                        columns = ImageDimensions(1,2);
                        
                        NoiseImage = image2;                % Creating the new noise image 
                        
                            %p = corruption_rate/100;        % Calculation propbablity rate if corruption rate is given in percentage  
                            p_n = (rows*columns)*corruption_rate;         % Calc number of corruputed pixels 
                        
                    n_min = 0;  % The range was given to us so we know min is 0 
                    n_max = 255; % The range was given to us so we know max is 255 
                    
                            for i=1:p_n                     % Looping through p_n range 
                                x = randi(rows);            % Generate random x
                                y = randi(columns);         % Generate random y
                                NoiseImage(x,y) = randi([n_min,n_max]);      % Change intesity value
                            end   
                    
                    NoiseImage = cat(3, NoiseImage, cbImage, crImage);
                    NoiseImage =  ycbcr2rgb(NoiseImage);

                else 

                    [R, G, B] = imsplit(image);

                    NoiseImage = image;
                    noiseR = double(R); 
                    noiseG = double(G); 
                    noiseB = double(B);

                    ImageDimensions = size(image); % Finding the dimensions of the image array,rows being N and columns as M
                    rows = ImageDimensions(1,1);
                    columns = ImageDimensions(1,2);
                
                    p_n = (rows*columns)*corruption_rate;         % Calc number of corruputed pixels 
                
                    n_min = 0;  % The range was given to us so we know min is 0 
                    n_max = 255; % The range was given to us so we know max is 255 
                    
                    for i=1:p_n                     % Looping through p_n range 
                        x_r = randi(rows);            % Generate random x
                        y_r = randi(columns);         % Generate random y
                        x_g = randi(rows);            % Generate random x
                        y_g = randi(columns);         % Generate random y
                        x_b = randi(rows);            % Generate random x
                        y_b = randi(columns);         % Generate random y
                        noiseR(x_r,y_r) = randi([n_min,n_max]); % Change intesity value
                        noiseG(x_g,y_g) = randi([n_min,n_max]); % Change intesity value
                        noiseB(x_b,y_b) = randi([n_min,n_max]);% Change intesity value
                    end 
                        % combine channels
                        noiseR = uint8(noiseR);
                        noiseG = uint8(noiseG);
                        noiseB = uint8(noiseB);
               
                        NoiseImage = cat(3, noiseR, noiseG, noiseB);
                end
                    NoiseImage = uint8(NoiseImage);                                     % Converting the image to uint8 type 
             end
% ----------- END OF IMPULSE NOISE FUNCTION -----------------%%%

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
         % Ensure NoiseImage is available in the SaveButton context
            if isempty(app.NoiseImageGaussian)
                uialert(app.UIFigure, 'No image available to save. Generate a noise image first.', 'Error');
                return;
            end
            
            % Ask user to specify the save location and filename
            [file, path] = uiputfile({'*.tif'}, 'Save Gaussian Noise Image As');
            
            if isequal(file, 0)
                % User canceled the operation
                return;
            end
            
            % Construct full file path and save the image
            fullFileName = fullfile(path, file);
            imwrite(app.NoiseImageGaussian, fullFileName);
            uialert(app.UIFigure, ['Image saved successfully at ', fullFileName], 'Success');
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            % Ensure NoiseImage is available in the SaveButton context
            if isempty(app.NoiseImageImpulse)
                uialert(app.UIFigure, 'No image available to save. Generate a noise image first.', 'Error');
                return;
            end
            
            % Ask user to specify the save location and filename
            [file, path] = uiputfile({'*.tif'}, 'Save Impulse Noise Image As');
            
            if isequal(file, 0)
                % User canceled the operation
                return;
            end
            
            % Construct full file path and save the image
            fullFileName = fullfile(path, file);
            imwrite(app.NoiseImageImpulse, fullFileName);
            uialert(app.UIFigure, ['Image saved successfully at ', fullFileName], 'Success');
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [file, path] = uigetfile("*.tif", "Select an Image File");
            
            if isequal(file, 0)
               return; % Exit the function if no file is selected
            end

            fullFilePath = fullfile(path, file);
            app.noiseimage = imread(fullFilePath);

            app.ImportimageyouwouldliketofilterEditField.Value = file;

        end

        % Value changed function: MedianFilter3x3windowCheckBox
        function MedianFilter3x3windowCheckBoxValueChanged(app, event)
            value = app.MedianFilter3x3windowCheckBox.Value;

            if (value == true)
                image = app.noiseimage; 
                channel = app.ChannelDropDown_2.Value; 
                [FilteredImage] = median_filtering(image,channel); 

                    app.FilteredImage_Median = FilteredImage;
                    I = imshow(FilteredImage, 'Parent', app.UIAxes4, ...
                    'XData', [1 app.UIAxes4.Position(3)], ...
                    'YData', [1 app.UIAxes4.Position(4)]);
                    
                    % Set limits of axes
                    app.UIAxes4.XLim = [0 I.XData(2)];
                    app.UIAxes4.YLim = [0 I.YData(2)];
            else 
                 cla(app.UIAxes4);
            end

% -------------------------------- Median Filter ----------------------------------- %

function [FilteredImage] = median_filtering(image,channel) 
            
                ImageDimensions = size(image); % Finding the dimensions of the image array,rows being N and columns as M
                rows = ImageDimensions(1,1);
                columns = ImageDimensions(1,2);
                FilteredImage = zeros(rows,columns);

                if channel == "Luminosity"
                    % ------ Using Y Channel ------ %
                    YCBCR = rgb2ycbcr(image);
                    [YImage, cbImage, crImage] = imsplit(YCBCR);
                                        
                    
                    mirror_imageY = mirrorImage(YImage,3);
                    mirror_imageY = double(mirror_imageY); % Converting unit8 type to double
                    
                    
                    for x = 2:rows+1
                        for y = 2:columns+1
                            window = mirror_imageY(x-1:x+1, y-1:y+1);        % 3x3 window 
                            window = window(:);                             % Vecotrize the matrix 
                            window = sorting(window, 9);                    % Sort the array
                            FilteredImage(x-1,y-1) = window(5);             % Replace intensity with median of the 1x9 array 
                        end
                    end
                    
                    FilteredImage = cat(3, FilteredImage, cbImage, crImage);
                    FilteredImage =  ycbcr2rgb(FilteredImage);
                    FilteredImage = uint8(FilteredImage);
                else  
                    % ------ Using RGB Channels ------ %
                    [R, G, B] = imsplit(image);
                                            
                    mirror_imageR = mirrorImage(R,3);
                    mirror_imageR = double(mirror_imageR); 
                    
                    mirror_imageG = mirrorImage(G,3);
                    mirror_imageG = double(mirror_imageG); 
                    
                    mirror_imageB = mirrorImage(B,3);
                    mirror_imageB = double(mirror_imageB); 
                    
                    FilteredImageR = zeros(rows,columns);
                    FilteredImageG = zeros(rows,columns);
                    FilteredImageB = zeros(rows,columns);
                    
                    for x = 2:rows+1
                        for y = 2:columns+1
                            
                            windowR = mirror_imageR(x-1:x+1, y-1:y+1);        % 3x3 window 
                            windowR = windowR(:);                             % Vecotrize the matrix 
                            windowR = sorting(windowR, 9);                    % Sort the array
                            FilteredImageR(x-1,y-1) = windowR(5);             % Replace intensity with median of the 1x9 array 
                            
                            windowG = mirror_imageG(x-1:x+1, y-1:y+1);        % 3x3 window 
                            windowG = windowG(:);                             % Vecotrize the matrix 
                            windowG = sorting(windowG, 9);                    % Sort the array
                            FilteredImageG(x-1,y-1) = windowG(5);             % Replace intensity with median of the 1x9 array 
                    
                            windowB = mirror_imageB(x-1:x+1, y-1:y+1);        % 3x3 window 
                            windowB = windowB(:);                             % Vecotrize the matrix 
                            windowB = sorting(windowB, 9);                    % Sort the array
                            FilteredImageB(x-1,y-1) = windowB(5);             % Replace intensity with median of the 1x9 array 
                        end
                    end
                    
                    FilteredImage = cat(3, FilteredImageR, FilteredImageG, FilteredImageB);
                    FilteredImage = uint8(FilteredImage);
                end 
            end 
            
            function [sorted_array] = sorting(A, size)
            
            for j = 2:size
                key = A(j);
                i=j-1;
                while(i>0 && key<A(i))
                    A(i+1)=A(i);
                    i=i-1;
                end 
                A(i+1)=key;
            end
            sorted_array = A;
end 

% ---------------------------- END OF MEDIAN FILTERING FUNCTION ------------------------------%%%

                    % --------------------------------- Mirror Function ---------------------------------- %
            function [ C ] = mirrorImage( image,kernel )
                % This function extends an image contained in the image matrix
                % mirroring it in all directions to be able then to use a local window
                % of the kernel x kernel size (kernel = 3, 5, or 7) around each pixel
                
                C = image;
                imSize = size(C);
                rowSize = imSize(1);
                colSize = imSize(2);
                
                %get the right size mirror depending on the size of the window
                if kernel == 3
                    mirror = 3;
                end
                if kernel == 5
                    mirror = 4;
                     
                end
                if kernel == 7
                    mirror = 5;
                   
                end
                
                %this section mirrors the top and left
                diff = 1;% used to help grab the right row for mirroring of different size windows
                for l = 3: mirror
                C = [ C(1:rowSize,l-diff) C];
                colSize = colSize + 1;
                C = [ C(l-diff,1:colSize); C];
                rowSize = rowSize + 1;
                
                diff = diff - 1;
                end
                
                %this section mirrors the bottom and right
                diff = 0;% used to grab the correct row because it changes with each
                            %increase in column and row
                 for t = 1 : mirror - 2
                C = [C C(1:rowSize, colSize - t - diff)];
                colSize = colSize + 1;
                diff = diff + 1;
                 end
                 diff = 0;
                 for s = 1 : mirror - 2
                C = [C ; C(rowSize - s - diff, 1:colSize)];
                rowSize = rowSize + 1;
                diff = diff + 1;
                 end
            end

        end

        % Value changed function: ImpluseNoiseDRIDCheckBox
        function ImpluseNoiseDRIDCheckBoxValueChanged(app, event)
            value = app.ImpluseNoiseDRIDCheckBox.Value;
           
            if value
                image = app.noiseimage; 
                channel = app.ChannelDropDown_2.Value; 

                    r_value = app.rEditField.Value;
                    s_value = app.sEditField.Value;

                [FilteredImage] = DRID_filtering(image,r_value,s_value, channel); 
                app.FilteredImage_DRID = FilteredImage;
                    I = imshow(FilteredImage, 'Parent', app.UIAxes5, ...
                    'XData', [1 app.UIAxes5.Position(3)], ...
                    'YData', [1 app.UIAxes5.Position(4)]);
                    
                    % Set limits of axes
                    app.UIAxes5.XLim = [0 I.XData(2)];
                    app.UIAxes5.YLim = [0 I.YData(2)];

            else 
                 cla(app.UIAxes5);
            end
 % --------------- differential rank impulse detector (DRID) -------------- %%
function [FilteredImage] = DRID_filtering(image,r,s, channel) 
                       
                        ImageDimensions = size(image); % Finding the dimensions of the image array,rows being N and columns as M
                        rows = ImageDimensions(1,1);
                        columns = ImageDimensions(1,2);
                        FilteredImage = zeros(rows,columns);    

                if(channel == "Luminosity")
                        % ------- Using Y channel ------ %
                        YCBCR = rgb2ycbcr(image);
                        [YImage, cbImage, crImage] = imsplit(YCBCR);
                                                           
                        mirror_image = mirrorImage(YImage,3);
                        mirror_image = double(mirror_image); % Converting unit8 type to double
                        
                        
                        for x = 2:rows+1
                            for y = 2:columns+1
                                window = mirror_image(x-1:x+1, y-1:y+1);        % 3x3 window of noise 
                                window = window(:);                             % Vecotrize the matrix into an 1D array 
                                window = sort(window);                    % Sort the array to get variational series 
                                
                                median_Y = median(window);
                                pixel_interest = mirror_image(x,y); 
                                index = find(window==pixel_interest);
                                
                                if index <=r
                                    difference = window(index+1) - window(index);
                                    if difference > s
                                        FilteredImage(x-1,y-1) = median_Y;             % Replace intensity it is an impulse
                                    else
                                        FilteredImage(x-1, y-1) = pixel_interest;
                                    end
                                elseif ((9-r)<index)
                                    difference = window(index-1)-window(index);
                                    if difference>s
                                         FilteredImage(x-1,y-1) = median_Y;             % Replace intensity it is an impulse
                                    else
                                        FilteredImage(x-1, y-1) = pixel_interest;
                                    end
                                else
                                    FilteredImage(x-1, y-1) = pixel_interest;
                                end
        
                            end
                        end
                        
                        FilteredImage = cat(3, FilteredImage, cbImage, crImage);
                        FilteredImage =  ycbcr2rgb(FilteredImage);
                        FilteredImage = uint8(FilteredImage);
                
                else 
                        % ------- Using RGB channels ------ %
                        [R, G, B] = imsplit(image);
                        
                        
                        mirror_imageR = mirrorImage(R,3);
                        mirror_imageR = double(mirror_imageR); 
                        
                        mirror_imageG = mirrorImage(G,3);
                        mirror_imageG = double(mirror_imageG); 
                        
                        mirror_imageB = mirrorImage(B,3);
                        mirror_imageB = double(mirror_imageB); 
                        
                        FilteredImageR = zeros(rows,columns);
                        FilteredImageG = zeros(rows,columns);
                        FilteredImageB = zeros(rows,columns);
                        
                                                               
                        for x = 2:rows+1
                            for y = 2:columns+1
                                windowR = mirror_imageR(x-1:x+1, y-1:y+1);       % 3x3 window of noise
                                windowR = windowR(:);                             % Vecotrize the matrix 
                                windowR = sort(windowR);                    % Sort the array to get variational series 
                                
                                windowG = mirror_imageG(x-1:x+1, y-1:y+1);        % 3x3 window of noise
                                windowG = windowG(:);                             % Vecotrize the matrix 
                                windowG = sort(windowG);                    % Sort the array to get variational series 
                        
                                windowB = mirror_imageB(x-1:x+1, y-1:y+1);        % 3x3 window of noise
                                windowB = windowB(:);                             % Vecotrize the matrix 
                                windowB = sort(windowB);                    % Sort the array to get variational series 
                        
                                medianR = median(windowR);
                                pixel_interestR = mirror_imageR(x,y); 
                                indexR = find(windowR==pixel_interestR);
        
                                medianG = median(windowG);
                                pixel_interestG = mirror_imageG(x,y); 
                                indexG = find(windowG==pixel_interestG);
        
                                medianB = median(windowB);
                                pixel_interestB = mirror_imageB(x,y); 
                                indexB = find(windowB==pixel_interestB);
        
                                if indexR <=r
                                    differenceR = windowR(indexR+1) - windowR(indexR);
                                    if differenceR > s
                                        FilteredImageR(x-1,y-1) = medianR;             % Replace intensity it is an impulse
                                    else
                                        FilteredImageR(x-1, y-1) = pixel_interestR;
                                    end
                                elseif ((9-r)<indexR)
                                    differenceR = windowR(indexR-1)-windowR(indexR);
                                    if differenceR>s
                                         FilteredImageR(x-1,y-1) = medianR;             % Replace intensity it is an impulse
                                    else
                                        FilteredImageR(x-1, y-1) = pixel_interestR;
                                    end
                                else
                                    FilteredImageR(x-1, y-1) = pixel_interestR;
                                end
        
        
                                 if indexG <=r
                                    differenceG = windowG(indexG+1) - windowG(indexG);
                                    if differenceG > s
                                        FilteredImageG(x-1,y-1) = medianG;             % Replace intensity it is an impulse
                                    else
                                        FilteredImageG(x-1, y-1) = pixel_interestG;
                                    end
                                elseif ((9-r)<indexG)
                                    differenceG = windowG(indexG-1)-windowG(indexG);
                                    if differenceG>s
                                         FilteredImageG(x-1,y-1) = medianG;             % Replace intensity it is an impulse
                                    else
                                        FilteredImageG(x-1, y-1) = pixel_interestG;
                                    end
                                else
                                    FilteredImageG(x-1, y-1) = pixel_interestG;
                                end
        
        
                                if indexB <=r
                                    differenceB = windowB(indexB+1) - windowB(indexB);
                                    if differenceB > s
                                        FilteredImageB(x-1,y-1) = medianB;             % Replace intensity it is an impulse
                                    else
                                        FilteredImageB(x-1, y-1) = pixel_interestB;
                                    end
                                elseif ((9-r)<indexB)
                                    differenceB = windowB(indexB-1)-windowB(indexB);
                                    if differenceB>s
                                         FilteredImageB(x-1,y-1) = medianB;             % Replace intensity it is an impulse
                                    else
                                        FilteredImageB(x-1, y-1) = pixel_interestB;
                                    end
                                else
                                    FilteredImageB(x-1, y-1) = pixel_interestB;
                                end
        
                            end
                        end
                        
                        FilteredImage = cat(3, FilteredImageR, FilteredImageG, FilteredImageB);
                        FilteredImage = uint8(FilteredImage);
                end
end 
                
                
 % -------------------------- END OF DRID FUNCTION ------------------------------------%%%


                    % --------------------------------- Mirror Function ---------------------------------- %
            function [ C ] = mirrorImage( image,kernel )
                % This function extends an image contained in the image matrix
                % mirroring it in all directions to be able then to use a local window
                % of the kernel x kernel size (kernel = 3, 5, or 7) around each pixel
                
                C = image;
                imSize = size(C);
                rowSize = imSize(1);
                colSize = imSize(2);
                
                %get the right size mirror depending on the size of the window
                if kernel == 3
                    mirror = 3;
                end
                if kernel == 5
                    mirror = 4;
                     
                end
                if kernel == 7
                    mirror = 5;
                   
                end
                
                %this section mirrors the top and left
                diff = 1;% used to help grab the right row for mirroring of different size windows
                for l = 3: mirror
                C = [ C(1:rowSize,l-diff) C];
                colSize = colSize + 1;
                C = [ C(l-diff,1:colSize); C];
                rowSize = rowSize + 1;
                
                diff = diff - 1;
                end
                
                %this section mirrors the bottom and right
                diff = 0;% used to grab the correct row because it changes with each
                            %increase in column and row
                 for t = 1 : mirror - 2
                C = [C C(1:rowSize, colSize - t - diff)];
                colSize = colSize + 1;
                diff = diff + 1;
                 end
                 diff = 0;
                 for s = 1 : mirror - 2
                C = [C ; C(rowSize - s - diff, 1:colSize)];
                rowSize = rowSize + 1;
                diff = diff + 1;
                 end
            end
        end

        % Value changed function: RankorderEVmeanCheckBox
        function RankorderEVmeanCheckBoxValueChanged(app, event)
            value = app.RankorderEVmeanCheckBox.Value;
            
            if (value)
                image = app.noiseimage; 
                channel = app.ChannelDropDown_2.Value; 
                [FilteredImage] = rank_order_Mean_EV_filtering(image,channel);
                
                    app.FilteredImage_EV = FilteredImage;
                    I = imshow(FilteredImage, 'Parent', app.UIAxes6, ...
                    'XData', [1 app.UIAxes6.Position(3)], ...
                    'YData', [1 app.UIAxes6.Position(4)]);
                    
                    % Set limits of axes
                    app.UIAxes6.XLim = [0 I.XData(2)];
                    app.UIAxes6.YLim = [0 I.YData(2)];
            else
                 cla(app.UIAxes6);
            end
 % -------------------------- RANK ORDER MEAN EV FUNCTION ------------------------------------%%%
function [FilteredImage] = rank_order_Mean_EV_filtering(image,channel)
                
                ImageDimensions = size(image); % Finding the dimensions of the image array,rows being N and columns as M
                rows = ImageDimensions(1,1);
                columns = ImageDimensions(1,2);
                FilteredImage = zeros(rows,columns);

                    if(channel == "Luminosity")
                        % ------- Using Y channel ------ %
                        YCBCR = rgb2ycbcr(image);
                        [YImage, cbImage, crImage] = imsplit(YCBCR);
                                                              % Looping through the image array to find the max, min, and mean values 
                        mirror_image = mirrorImage(YImage,3);
                        mirror_image = double(mirror_image); % Converting unit8 type to double
                        
                        E_v = std(double(YImage)); 
                        
                        for x = 2:rows+1
                            for y = 2:columns+1
                                window = mirror_image(x-1:x+1, y-1:y+1);        % 3x3 window of noise 
                                window = window(:);                             % Vecotrize the matrix into an 1D array 
                                window = sort(window);                    % Sort the array to get variational series 
                        
                                EV_set= []; % Get EV set 
                                for i =1:9                                      % Looping through variational series 
                                    if((mirror_image(x,y) - window(i)) <= E_v)  % Checking condition if those intensity values whose difference from the intensity value in the pixel of interest does not exceed E_V
                                        EV_set(end+1,1) = window(i);            % Adding that insentisty value to the end of the EV set 
                                    end                                         % End if staement 
                                end                                             % End for loop 
                                
                                l = length(EV_set);   % Get size of set 
                                sum_=0;
                                for i= 1:l
                                    sum_=sum_+EV_set(i);  % Update sum to get mean
                                end
                        
                                Mean_EV = sum_/l;                       % Finding the mean of the EV set 
                                FilteredImage(x-1,y-1) = Mean_EV;             % Replace intensity with EV mean 
                            end
                        end
                        
                        FilteredImage = cat(3, FilteredImage, cbImage, crImage);
                        FilteredImage =  ycbcr2rgb(FilteredImage);
                        FilteredImage = uint8(FilteredImage);
                        
                    else 
                        % ------- Using RGB channels ------ %
                        [R, G, B] = imsplit(image);
                        
                        imageR = double(R);
                        imageG = double(G);
                        imageB = double(B);
                        
                        mirror_imageR = mirrorImage(imageR,3);
                        mirror_imageR = double(mirror_imageR); 
                        
                        mirror_imageG = mirrorImage(imageG,3);
                        mirror_imageG = double(mirror_imageG); 
                        
                        mirror_imageB = mirrorImage(imageB,3);
                        mirror_imageB = double(mirror_imageB); 
                        
                        FilteredImageR = zeros(rows,columns);
                        FilteredImageG = zeros(rows,columns);
                        FilteredImageB = zeros(rows,columns);
                        
                        
                        E_v_R = std(double(imageR)); 
                       
                        E_v_G = std(double(imageG)); 
                        
                        E_v_B = std(double(imageB));
                                                               
                        for x = 2:rows+1
                            for y = 2:columns+1
                                windowR = mirror_imageR(x-1:x+1, y-1:y+1);       % 3x3 window of noise
                                windowR = windowR(:);                             % Vecotrize the matrix 
                                windowR = sort(windowR);                    % Sort the array to get variational series 
                                
                                windowG = mirror_imageG(x-1:x+1, y-1:y+1);        % 3x3 window of noise
                                windowG = windowG(:);                             % Vecotrize the matrix 
                                windowG = sort(windowG);                    % Sort the array to get variational series 
                        
                                windowB = mirror_imageB(x-1:x+1, y-1:y+1);        % 3x3 window of noise
                                windowB = windowB(:);                             % Vecotrize the matrix 
                                windowB = sort(windowB);                    % Sort the array to get variational series 
                        
                        
                                EV_set_R= []; % Get EV_R set 
                                EV_set_G= []; % Get EV_G set 
                                EV_set_B= []; % Get EV_B set 
                        
                                for i =1:9                                      % Looping through variational series 
                                    if((mirror_imageR(x,y) - windowR(i)) <= E_v_R)  % Checking condition if those intensity values whose difference from the intensity value in the pixel of interest does not exceed E_V
                                        EV_set_R(end+1,1) = windowR(i);            % Adding that insentisty value to the end of the EV set 
                                    end                                         % End if staement 
                        
                                    if((mirror_imageG(x,y) - windowG(i)) <= E_v_G)  % Checking condition if those intensity values whose difference from the intensity value in the pixel of interest does not exceed E_V
                                        EV_set_G(end+1,1) = windowG(i);            % Adding that insentisty value to the end of the EV set 
                                    end                                         % End if staement 
                        
                                    if((mirror_imageB(x,y) - windowB(i)) <= E_v_B)  % Checking condition if those intensity values whose difference from the intensity value in the pixel of interest does not exceed E_V
                                        EV_set_B(end+1,1) = windowB(i);            % Adding that insentisty value to the end of the EV set 
                                    end                                         % End if staement 
                                end                                             % End for loop 
                                
                                l = length(EV_set_R);   % Get size of set 
                                sum_=0;
                                for i= 1:l
                                    sum_=sum_+EV_set_R(i);  % Update sum to get mean
                                end
                                Mean_EV = sum_/l;                       % Finding the mean of the EV set 
                                FilteredImageR(x-1,y-1) = Mean_EV;             % Replace intensity with EV mean 
                        
                        
                                l = length(EV_set_G);   % Get size of set 
                                sum_=0;
                                for i= 1:l
                                    sum_=sum_+EV_set_G(i);  % Update sum to get mean
                                end
                                Mean_EV = sum_/l;                       % Finding the mean of the EV set 
                                FilteredImageG(x-1,y-1) = Mean_EV;             % Replace intensity with EV mean 
                        
                        
                                l = length(EV_set_B);   % Get size of set 
                                sum_=0;
                                for i= 1:l
                                    sum_=sum_+EV_set_B(i);  % Update sum to get mean
                                end
                                Mean_EV = sum_/l;                       % Finding the mean of the EV set 
                                FilteredImageB(x-1,y-1) = Mean_EV;             % Replace intensity with EV mean       
                            end
                        end
                        
                        FilteredImage = cat(3, FilteredImageR, FilteredImageG, FilteredImageB);
                        FilteredImage = uint8(FilteredImage);
                    end
end 
% ----------- END OF RANK ORDER MEAN EV FUNCTION -----------------%%%
% --------------------------------- Mirror Function ---------------------------------- %
            function [ C ] = mirrorImage( image,kernel )
                % This function extends an image contained in the image matrix
                % mirroring it in all directions to be able then to use a local window
                % of the kernel x kernel size (kernel = 3, 5, or 7) around each pixel
                
                C = image;
                imSize = size(C);
                rowSize = imSize(1);
                colSize = imSize(2);
                
                %get the right size mirror depending on the size of the window
                if kernel == 3
                    mirror = 3;
                end
                if kernel == 5
                    mirror = 4;
                     
                end
                if kernel == 7
                    mirror = 5;
                   
                end
                
                %this section mirrors the top and left
                diff = 1;% used to help grab the right row for mirroring of different size windows
                for l = 3: mirror
                C = [ C(1:rowSize,l-diff) C];
                colSize = colSize + 1;
                C = [ C(l-diff,1:colSize); C];
                rowSize = rowSize + 1;
                
                diff = diff - 1;
                end
                
                %this section mirrors the bottom and right
                diff = 0;% used to grab the correct row because it changes with each
                            %increase in column and row
                 for t = 1 : mirror - 2
                C = [C C(1:rowSize, colSize - t - diff)];
                colSize = colSize + 1;
                diff = diff + 1;
                 end
                 diff = 0;
                 for s = 1 : mirror - 2
                C = [C ; C(rowSize - s - diff, 1:colSize)];
                rowSize = rowSize + 1;
                diff = diff + 1;
                 end
            end

        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            if isempty(app.FilteredImage_Median)
                uialert(app.UIFigure, 'No image available to save. Generate a noise image first.', 'Error');
                return;
            end
            
            % Ask user to specify the save location and filename
            [file, path] = uiputfile({'*.tif'}, 'Save Filtered Image As');
            
            if isequal(file, 0)
                % User canceled the operation
                return;
            end
            
            % Construct full file path and save the image
            fullFileName = fullfile(path, file);
            imwrite(app.FilteredImage_Median, fullFileName);
            uialert(app.UIFigure, ['Image saved successfully at ', fullFileName], 'Success');
        end

        % Button pushed function: SaveButton_5
        function SaveButton_5Pushed(app, event)
            if isempty(app.FilteredImage_EV)
                uialert(app.UIFigure, 'No image available to save. Generate a noise image first.', 'Error');
                return;
            end
            
            % Ask user to specify the save location and filename
            [file, path] = uiputfile({'*.tif'}, 'Save Filtered Image As');
            
            if isequal(file, 0)
                % User canceled the operation
                return;
            end
            
            % Construct full file path and save the image
            fullFileName = fullfile(path, file);
            imwrite(app.FilteredImage_EV, fullFileName);
            uialert(app.UIFigure, ['Image saved successfully at ', fullFileName], 'Success');
        end

        % Button pushed function: BrowseButton_5
        function BrowseButton_5Pushed(app, event)
            [file, path] = uigetfile("*.tif", "Select an Image File");
            
            if isequal(file, 0)
               return; % Exit the function if no file is selected
            end

            fullFilePath = fullfile(path, file);
            app.image1 = imread(fullFilePath);

            app.ImportfirstimageEditField.Value = file;
           
            channel = app.ChannelDropDown_3.Value;
        end

        % Button pushed function: BrowseButton_4
        function BrowseButton_4Pushed(app, event)
            [file, path] = uigetfile("*.tif", "Select an Image File");
            
            if isequal(file, 0)
               return; % Exit the function if no file is selected
            end

            fullFilePath = fullfile(path, file);
            app.image2 = imread(fullFilePath);
    
            app.ImportsecondimageEditField.Value = file;

            channel = app.ChannelDropDown_3.Value;
        end

        % Button pushed function: BrowsetocalculateButton
        function BrowsetocalculateButtonPushed(app, event)
            [file, path] = uigetfile("*.tif", "Select an Image File");
            
            if isequal(file, 0)
               return; % Exit the function if no file is selected
            end

            fullFilePath = fullfile(path, file);
            app.mean_std_image = imread(fullFilePath);
            
            app.ImportimagetofindmeanandstandarddeviationEditField.Value = file;

            channel = app.ChannelDropDown_3.Value;

            [mean_Y,stand_Y,mean_RGB, stand_RGB] = mean_std(app.mean_std_image);
            
            if (channel == "Luminosity")
                app.UITable.Data = { mean_Y, stand_Y};
            else
                app.UITable.Data = {
                    mean_RGB(1), stand_RGB(1);
                    mean_RGB(2), stand_RGB(2);
                    mean_RGB(3), stand_RGB(3)
                };
            end

function [mean_Y,stand_Y,mean_RGB, stand_RGB] = mean_std(image)

% ------- Using DIFFERENT channel ------ %
YCBCR = rgb2ycbcr(image);
[YImage, cbImage, crImage] = imsplit(YCBCR);
YImage = double(YImage);

[R, G, B] = imsplit(image);

imageR = double(R);
imageG = double(G);
imageB = double(B);


ImageDimensions = size(YImage); % Finding the dimensions of the image array,rows being N and columns as M
rows = ImageDimensions(1,1);
columns = ImageDimensions(1,2);

meanSum_Y = 0 ;  % Setting a sum variable to calculate mean 
sum_Y = 0; % Setting a sum varibale which will be used to calculate variance 

meanSum_R = 0 ;  % Setting a sum variable to calculate mean 
sum_R = 0; % Setting a sum varibale which will be used to calculate variance 

meanSum_G = 0 ;  % Setting a sum variable to calculate mean 
sum_G = 0; % Setting a sum varibale which will be used to calculate variance 

meanSum_B = 0 ;  % Setting a sum variable to calculate mean 
sum_B = 0; % Setting a sum varibale which will be used to calculate variance 


% Looping through the image array to find mean values 
for i = 1:1:rows  
    for j = 1:1:columns
        meanSum_Y = meanSum_Y + YImage(i,j); % Updating sum variable 
        meanSum_R = meanSum_R + imageR(i,j); % Updating sum variable 
        meanSum_G = meanSum_G + imageG(i,j); % Updating sum variable 
        meanSum_B = meanSum_B + imageB(i,j); % Updating sum variable 
    end 
end 

mean_Y = meanSum_Y/(rows*columns);    % Finding the mean (sampling mean)
mean_R = meanSum_R/(rows*columns);    % Finding the mean (sampling mean)
mean_G = meanSum_G/(rows*columns);    % Finding the mean (sampling mean)
mean_B = meanSum_B/(rows*columns);    % Finding the mean (sampling mean)

% Looping through the image arry to find the Variance 
for i = 1:1:rows  
    for j = 1:1:columns
        x = YImage(i,j) - mean_Y;
        sum_Y = sum_Y + x^2; % Updating sum variable 

        x = imageR(i,j) - mean_R;
        sum_R = sum_R + x^2; % Updating sum variable 

        x = imageG(i,j) - mean_G;
        sum_G = sum_G + x^2; % Updating sum variable 

        x = imageB(i,j) - mean_B;
        sum_B = sum_B + x^2; % Updating sum variable 
    end 
end 

variance_Y = sum_Y/(rows*columns); % Finding the variance (sampling variance)
stand_Y = sqrt(variance_Y);   % Finding the standard deviation 

variance_R = sum_R/(rows*columns); % Finding the variance (sampling variance)
stand_R = sqrt(variance_R);   % Finding the standard deviation 

variance_G = sum_G/(rows*columns); % Finding the variance (sampling variance)
stand_G = sqrt(variance_G);   % Finding the standard deviation 

variance_B = sum_B/(rows*columns); % Finding the variance (sampling variance)
stand_B = sqrt(variance_B);   % Finding the standard deviation 


    mean_RGB = [mean_R, mean_G, mean_B];
    stand_RGB = [stand_R, stand_G, stand_B];


end 
% ----------------------------------- END OF MEAN AND STD FUNCTION ----------------------------------- %

        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
            image1= app.image1;
            image2=app.image2;
            channel = app.ChannelDropDown_3.Value;
            
            [RMSE_Y, PSNR_Y, RMSE_RGB, PSNR_RGB] = RMSE_PSNR(image1,image2);
            if (channel == "Luminosity")
                app.UITable_2.Data = { RMSE_Y, PSNR_Y};
            else
                app.UITable_2.Data = {
                    RMSE_RGB(1), PSNR_RGB(1);
                    RMSE_RGB(2), PSNR_RGB(2);
                    RMSE_RGB(3), PSNR_RGB(3)
                };
            end
function [RMSE_Y, PSNR_Y, RMSE_RGB, PSNR_RGB] = RMSE_PSNR(image1,image2) % 

ImageDimensions1 = size(image1); % Finding the dimensions of the first image array,rows being N and columns as M
rows1 = ImageDimensions1(1,1);
columns1 = ImageDimensions1(1,2);

ImageDimensions2 = size(image2); % Finding the dimensions of the first image array,rows being N and columns as M
rows2 = ImageDimensions2(1,1);
columns2 = ImageDimensions2(1,2);

if((rows1~=rows2) || (columns1~=columns2))      % Keeping to see if the image dimensions match 
    disp("Can not find the RMSE and PSNR of these two images because their sizes are not the same.") % Display to the user the error 
    return;                                     % Return because the function can not be carried out 
end 

%-------- Y channel -------% 
YCBCR1 = rgb2ycbcr(image1);
[YImage1, cbImage1, crImage1] = imsplit(YCBCR1);
YImage1 = double(YImage1);

YCBCR2 = rgb2ycbcr(image2);
[YImage2, cbImage2, crImage2] = imsplit(YCBCR2);
YImage2 = double(YImage2);

sum_Y = 0;

%------ RGB channel -------- %

[R1, G1, B1] = imsplit(image1);
[R2, G2, B2] = imsplit(image2);

R1 = double(R1);
R2 = double(R2);
G1 = double(G1);
G2 = double(G2);
B1 = double(B1);
B2 = double(B2);

sum_R = 0;
sum_G = 0;
sum_B = 0;


for i = 1:rows1
    for j = 1:columns1
        % Luminosity channel
        diff_Y = YImage1(i, j) - YImage2(i, j);
        sum_Y = sum_Y + diff_Y^2;

        % Red channel
        diff_R = R1(i, j) - R2(i, j);
        sum_R = sum_R + diff_R^2;

        % Green channel
        diff_G = G1(i, j) - G2(i, j);
        sum_G = sum_G + diff_G^2;

        % Blue channel
        diff_B = B1(i, j) - B2(i, j);
        sum_B = sum_B + diff_B^2;
    end
end
% End outter loop 

MSE_Y = sum_Y / (rows1 * columns1);
RMSE_Y = sqrt(MSE_Y);

MSE_R = sum_R / (rows1 * columns1);
RMSE_R = sqrt(MSE_R);

MSE_G = sum_G / (rows1 * columns1);
RMSE_G = sqrt(MSE_G);

MSE_B = sum_B / (rows1 * columns1);
RMSE_B = sqrt(MSE_B);

% PSNR calculations
PSNR_Y = 20 * log10(255 / RMSE_Y);
PSNR_R = 20 * log10(255 / RMSE_R);
PSNR_G = 20 * log10(255 / RMSE_G);
PSNR_B = 20 * log10(255 / RMSE_B);

% Assign outputs
RMSE_RGB = [RMSE_R, RMSE_G, RMSE_B];
PSNR_RGB = [PSNR_R, PSNR_G, PSNR_B];

end
% ----------------------------------- END OF RMSE AND PSNR FUNCTION ----------------------------------- %

        end

        % Button pushed function: SaveButton_4
        function SaveButton_4Pushed(app, event)
            
            if isempty(app.FilteredImage_DRID)
                uialert(app.UIFigure, 'No image available to save. Generate a noise image first.', 'Error');
                return;
            end
            
            % Ask user to specify the save location and filename
            [file, path] = uiputfile({'*.tif'}, 'Save Filtered Image As');
            
            if isequal(file, 0)
                % User canceled the operation
                return;
            end
            
            % Construct full file path and save the image
            fullFileName = fullfile(path, file);
            imwrite(app.FilteredImage_DRID, fullFileName);
            uialert(app.UIFigure, ['Image saved successfully at ', fullFileName], 'Success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UploadImageTab = uitab(app.TabGroup);
            app.UploadImageTab.Title = 'Upload Image';
            app.UIAxes = uiaxes(app.UploadImageTab);
            title(app.UIAxes, 'Image Display')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UploadyourcolorimagehereLabel = uilabel(app.UploadImageTab);
            app.UploadyourcolorimagehereLabel.Text = 'Upload your color image here ';
            app.ImageNameEditFieldLabel = uilabel(app.UploadImageTab);
            app.ImageNameEditFieldLabel.Text = 'Image Name ';
            app.ImageNameEditField = uieditfield(app.UploadImageTab, 'text');
            app.BrowseButton = uibutton(app.UploadImageTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.NoiseModelingTab = uitab(app.TabGroup);
            app.NoiseModelingTab.Title = 'Noise Modeling';
            app.UIAxes2 = uiaxes(app.NoiseModelingTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.NoiseModelingTab);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.SelectwhichtypeofnoiseyouwouldliketoincorporateLabel = uilabel(app.NoiseModelingTab);
            app.SelectwhichtypeofnoiseyouwouldliketoincorporateLabel.Text = 'Select which type of noise you would like to incorporate ';
            app.AdditiveGaussianNoiseCheckBox = uicheckbox(app.NoiseModelingTab);
            app.AdditiveGaussianNoiseCheckBox.Text = 'Additive Gaussian Noise ';
            app.RandomImpulseNoiseCheckBox = uicheckbox(app.NoiseModelingTab);
            app.RandomImpulseNoiseCheckBox.Text = 'Random Impulse Noise ';
            app.SaveButton = uibutton(app.NoiseModelingTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveButton_2 = uibutton(app.NoiseModelingTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.alongwiththechannelsyouwanttouseLabel = uilabel(app.NoiseModelingTab);
            app.alongwiththechannelsyouwanttouseLabel.Text = 'along with the channel(s) you want to use: ';
            app.ChannelDropDownLabel = uilabel(app.NoiseModelingTab);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.NoiseModelingTab);
            app.ChannelDropDown.Items = {'Luminosity', 'RGB'};
            app.ChannelDropDown.Value = 'Luminosity';
            app.EntercoefficientEditFieldLabel = uilabel(app.NoiseModelingTab);
            app.EntercoefficientEditFieldLabel.Text = 'Enter coefficient';
            app.EntercoefficientEditField = uieditfield(app.NoiseModelingTab, 'numeric');
            app.EntercorruptionrateEditFieldLabel = uilabel(app.NoiseModelingTab);
            app.EntercorruptionrateEditFieldLabel.Text = 'Enter corruption rate ';
            app.EntercorruptionrateEditField = uieditfield(app.NoiseModelingTab, 'numeric');
            app.CalculateButton_2 = uibutton(app.NoiseModelingTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.CalculateButton_3 = uibutton(app.NoiseModelingTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.UIAxes4 = uiaxes(app.FiltersTab);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes5 = uiaxes(app.FiltersTab);
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes6 = uiaxes(app.FiltersTab);
            app.UIAxes6.XTick = [];
            app.UIAxes6.XTickLabel = '';
            app.UIAxes6.YTick = [];
            app.UIAxes6.YTickLabel = '';
            app.SelectthefollowingfiltersyouwouldlikeimplementedLabel = uilabel(app.FiltersTab);
            app.SelectthefollowingfiltersyouwouldlikeimplementedLabel.Text = 'Select the following filters you would like implemented  ';
            app.MedianFilter3x3windowCheckBox = uicheckbox(app.FiltersTab);
            app.MedianFilter3x3windowCheckBox.ValueChangedFcn = createCallbackFcn(app, @MedianFilter3x3windowCheckBoxValueChanged, true);
            app.MedianFilter3x3windowCheckBox.Text = 'Median Filter (3x3 window)';
            app.ImpluseNoiseDRIDCheckBox = uicheckbox(app.FiltersTab);
            app.ImpluseNoiseDRIDCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImpluseNoiseDRIDCheckBoxValueChanged, true);
            app.ImpluseNoiseDRIDCheckBox.Text = 'Impluse Noise (DRID)';
            app.RankorderEVmeanCheckBox = uicheckbox(app.FiltersTab);
            app.RankorderEVmeanCheckBox.ValueChangedFcn = createCallbackFcn(app, @RankorderEVmeanCheckBoxValueChanged, true);
            app.RankorderEVmeanCheckBox.Text = 'Rank-order EV (mean)';
            app.SaveButton_3 = uibutton(app.FiltersTab, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = 'Save';
            app.SaveButton_4 = uibutton(app.FiltersTab, 'push');
            app.SaveButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_4Pushed, true);
            app.SaveButton_4.Text = 'Save';
            app.SaveButton_5 = uibutton(app.FiltersTab, 'push');
            app.SaveButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_5Pushed, true);
            app.SaveButton_5.Text = 'Save';
            app.ImportimageyouwouldliketofilterEditFieldLabel = uilabel(app.FiltersTab);
            app.ImportimageyouwouldliketofilterEditFieldLabel.Text = 'Import image you would like to filter ';
            app.ImportimageyouwouldliketofilterEditField = uieditfield(app.FiltersTab, 'text');
            app.BrowseButton_2 = uibutton(app.FiltersTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.ChannelDropDown_2Label = uilabel(app.FiltersTab);
            app.ChannelDropDown_2Label.Text = 'Channel';
            app.ChannelDropDown_2 = uidropdown(app.FiltersTab);
            app.ChannelDropDown_2.Items = {'Luminosity', 'RGB'};
            app.ChannelDropDown_2.Value = 'Luminosity';
            app.rEditFieldLabel = uilabel(app.FiltersTab);
            app.rEditFieldLabel.Text = 'r:';
            app.rEditField = uieditfield(app.FiltersTab, 'numeric');
            app.sEditFieldLabel = uilabel(app.FiltersTab);
            app.sEditFieldLabel.Text = 's:';
            app.sEditField = uieditfield(app.FiltersTab, 'numeric');
            app.StatisticalCharacteristicsTab = uitab(app.TabGroup);
            app.StatisticalCharacteristicsTab.Title = 'Statistical Characteristics';
            app.UITable = uitable(app.StatisticalCharacteristicsTab);
            app.UITable.ColumnName = {'Mean'; 'Standard Deviation'};
            app.UITable.RowName = {};
            app.Label = uilabel(app.StatisticalCharacteristicsTab);
            app.Label.Text = 'Select which channel(s) you want to be used ';
            app.forstatisticalcharacteristicsLabel = uilabel(app.StatisticalCharacteristicsTab);
            app.forstatisticalcharacteristicsLabel.Text = 'for statistical characteristics: ';
            app.ChannelDropDown_3Label = uilabel(app.StatisticalCharacteristicsTab);
            app.ChannelDropDown_3Label.Text = 'Channel';
            app.ChannelDropDown_3 = uidropdown(app.StatisticalCharacteristicsTab);
            app.ChannelDropDown_3.Items = {'Luminosity', 'RGB'};
            app.ChannelDropDown_3.Value = 'Luminosity';
            app.ImportimagetofindmeanandstandarddeviationEditFieldLabel = uilabel(app.StatisticalCharacteristicsTab);
            app.ImportimagetofindmeanandstandarddeviationEditFieldLabel.Text = 'Import image to find mean and standard deviation ';
            app.ImportimagetofindmeanandstandarddeviationEditField = uieditfield(app.StatisticalCharacteristicsTab, 'text');
            app.UITable_2 = uitable(app.StatisticalCharacteristicsTab);
            app.UITable_2.ColumnName = {'RMSE'; 'PSNR'};
            app.UITable_2.RowName = {};
            app.CalculateButton_4 = uibutton(app.StatisticalCharacteristicsTab, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.ImportfirstimageEditFieldLabel = uilabel(app.StatisticalCharacteristicsTab);
            app.ImportfirstimageEditFieldLabel.Text = 'Import first image ';
            app.ImportfirstimageEditField = uieditfield(app.StatisticalCharacteristicsTab, 'text');
            app.ImportsecondimageEditFieldLabel = uilabel(app.StatisticalCharacteristicsTab);
            app.ImportsecondimageEditFieldLabel.Text = 'Import second image ';
            app.ImportsecondimageEditField = uieditfield(app.StatisticalCharacteristicsTab, 'text');
            app.BrowsetocalculateButton = uibutton(app.StatisticalCharacteristicsTab, 'push');
            app.BrowsetocalculateButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsetocalculateButtonPushed, true);
            app.BrowsetocalculateButton.Text = 'Browse to calculate ';
            app.BrowseButton_4 = uibutton(app.StatisticalCharacteristicsTab, 'push');
            app.BrowseButton_4.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_4Pushed, true);
            app.BrowseButton_4.Text = 'Browse  ';
            app.BrowseButton_5 = uibutton(app.StatisticalCharacteristicsTab, 'push');
            app.BrowseButton_5.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_5Pushed, true);
            app.BrowseButton_5.Text = 'Browse  ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end